classdef uniformQuantGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Label3           matlab.ui.control.Label
        Label2           matlab.ui.control.Label
        Label            matlab.ui.control.Label
        BSlider          matlab.ui.control.Slider
        BSliderLabel     matlab.ui.control.Label
        GSlider          matlab.ui.control.Slider
        GSliderLabel     matlab.ui.control.Label
        RSlider          matlab.ui.control.Slider
        RSliderLabel     matlab.ui.control.Label
        QuantizeButton   matlab.ui.control.Button
        LoadImageButton  matlab.ui.control.Button
        UIAxes2          matlab.ui.control.UIAxes
        UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Image = [] % An array for storing the loaded image
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set the labels text to 0
            app.Label.Text = '0';
            app.Label2.Text = '0';
            app.Label3.Text = '0';
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            % Prompt user for an image file
            [fileName, filePath] = uigetfile('*.png;*.jpg;*.jpeg;');

            % If user chooses not to select an image (clicked Cancel),
            % throw error
            if fileName == 0
                uialert(app.UIFigure, 'Please choose an image.', 'Error');
                return;
            end

            % Load selected image, store it into Image property
            app.Image = imread(fullfile(filePath, fileName));

            % Display image on first axis
            imshow(app.Image, 'Parent', app.UIAxes);
        end

        % Button pushed function: QuantizeButton
        function QuantizeButtonPushed(app, event)
            % Get values from sliders and round to nearest integer
            rBitDepth = round(app.RSlider.Value);
            gBitDepth = round(app.GSlider.Value);
            bBitDepth = round(app.BSlider.Value);

            % Quantize the image using the uniformQuant function and the
            % values from sliders
            quantizedImage = uniformQuant(app.Image, [rBitDepth, gBitDepth, bBitDepth]);

            % Display quantized image on second axis
            imshow(quantizedImage, 'Parent', app.UIAxes2);

            % Save each quantized image with a different filename. I chose
            % to just use the current date and time as the filename
            filename = ['quantizedImage ', datestr(now, 'mm:dd:yyyy hhMM AM'), '.png'];
            imwrite(quantizedImage, filename);
        end

        % Value changed function: RSlider
        function RSliderValueChanged(app, event)
            value = app.RSlider.Value;

            % Update label with the selected rounded slider value
            app.Label.Text = num2str(round(value));
        end

        % Value changed function: GSlider
        function GSliderValueChanged(app, event)
            value = app.GSlider.Value;

            % Update label with the selected rounded slider value
            app.Label2.Text = num2str(round(value));
        end

        % Value changed function: BSlider
        function BSliderValueChanged(app, event)
            value = app.BSlider.Value;

            % Update label with the selected rounded slider value
            app.Label3.Text = num2str(round(value));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Quantized')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.QuantizeButton = uibutton(app.UIFigure, 'push');
            app.QuantizeButton.ButtonPushedFcn = createCallbackFcn(app, @QuantizeButtonPushed, true);
            app.QuantizeButton.Text = 'Quantize';
            app.RSliderLabel = uilabel(app.UIFigure);
            app.RSliderLabel.Text = 'R';
            app.RSlider = uislider(app.UIFigure);
            app.RSlider.Limits = [1 8];
            app.RSlider.ValueChangedFcn = createCallbackFcn(app, @RSliderValueChanged, true);
            app.RSlider.MinorTicks = [];
            app.RSlider.Value = 1;
            app.GSliderLabel = uilabel(app.UIFigure);
            app.GSliderLabel.Text = 'G';
            app.GSlider = uislider(app.UIFigure);
            app.GSlider.Limits = [1 8];
            app.GSlider.ValueChangedFcn = createCallbackFcn(app, @GSliderValueChanged, true);
            app.GSlider.MinorTicks = [];
            app.GSlider.Value = 1;
            app.BSliderLabel = uilabel(app.UIFigure);
            app.BSliderLabel.Text = 'B';
            app.BSlider = uislider(app.UIFigure);
            app.BSlider.Limits = [1 8];
            app.BSlider.ValueChangedFcn = createCallbackFcn(app, @BSliderValueChanged, true);
            app.BSlider.MinorTicks = [];
            app.BSlider.Value = 1;
            app.Label = uilabel(app.UIFigure);
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'Label3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uniformQuantGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end